classdef SuperHirn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label_Chance_number  matlab.ui.control.Label
        Label_Chance_static  matlab.ui.control.Label
        SubmitButton         matlab.ui.control.Button
        StartButton          matlab.ui.control.Button
    end

    % Private properties
    properties (Access = public)
        numCols = 4;
        clrLst = {'Red', 'Blue', 'Magenta', 'Yellow',  'Green', 'Cyan'};
        clrNumLst = [];
        numClrs = 6;
        DropDown(10,4) matlab.ui.control.DropDown
        Lamp(1,4)      matlab.ui.control.Lamp
        HintLamp(10,4) matlab.ui.control.Lamp
        CoverBox       matlab.ui.control.Label
        rowNum = 0;
        TrueClrLst(1,4) string;
        SubmittedClrLst(1,4) string;
    end
    
    
    methods (Access = private)
       % Create row of colour lamps to deduce
       function createLampRow(app)
           for cc = 1:app.numCols
               app.Lamp(1,cc) = uilamp(app.UIFigure);
               app.Lamp(1,cc).Position = [127+(cc-1)*67 531 20 20];
               app.Lamp(1,cc).Color = [1 1 1];
           end

    
       end

       % Create a row of drop down colour list
        function createDropDownRow(app)
            app.rowNum = app.rowNum + 1;
            rr = app.rowNum;
            for cc = 1:app.numCols
                app.DropDown(rr,cc) = uidropdown(app.UIFigure);
                app.DropDown(rr,cc).Items = {'Select', app.clrLst{1:6}};
                app.DropDown(rr,cc).FontName = 'Bahnschrift';
                app.DropDown(rr,cc).Enable = 'on';
                app.DropDown(rr,cc).Visible = 'on';
                app.DropDown(rr,cc).Position = [105+(cc-1)*67 487-(rr-1)*21 68 22];
                app.DropDown(rr,cc).Value = 'Select';
                app.DropDown(rr,cc).Tag = ("R"+num2str(rr)+"C"+num2str(cc));
                app.DropDown(rr,cc).UserData = [rr,cc];
                app.DropDown(rr,cc).ValueChangedFcn = createCallbackFcn(app,@changeColour,true);
            end
            createHintRow(app)
            app.Label_Chance_number.Text = num2str(10-app.rowNum+1);
        end

        % Update drop down background colour
        function changeColour(app,event)
            rr = event.Source.UserData(1);
            cc = event.Source.UserData(2);
            if strcmp(event.Value,'Select')
                app.DropDown(rr,cc).BackgroundColor = [0.96, 0.96, 0.96];
            else
                app.DropDown(rr,cc).BackgroundColor = event.Value;
            end
            for jj = 1:app.numCols
                rowValues(jj) = string(app.DropDown(rr,jj).Value);
            end
            if ~ismember("Select",rowValues)
                app.SubmitButton.Enable = 'on';
            else
                app.SubmitButton.Enable = 'off';
            end
        end
        
        function createTargetColourRow(app)
           app.clrNumLst = randi(app.numClrs,1,app.numCols);
           for cc = 1:app.numCols
               app.Lamp(1,cc).Color = app.clrLst{app.clrNumLst(cc)};
               app.TrueClrLst(cc) = string(app.clrLst{app.clrNumLst(cc)});
           end
           
        end
        
        function createCoverBox(app)
            app.CoverBox = uilabel(app.UIFigure);
            app.CoverBox.BackgroundColor = [0.6863 0.8588 0.4745];
            app.CoverBox.HorizontalAlignment = 'center';
            app.CoverBox.FontName = 'Bahnschrift';
            app.CoverBox.Position = [105 529 270 24];
            app.CoverBox.Text = 'Deduce!';
        end
        
        function createHintRow(app)
            rr = app.rowNum;
            for cc = 1:app.numCols
                app.HintLamp(rr,cc) = uilamp(app.UIFigure);
                app.HintLamp(rr,cc).Position = [30+(cc-1)*15 492-(rr-1)*21 10 10];
                app.HintLamp(rr,cc).Color = [0.5 0.5 0.5];
            end
        end
        
        function results = checkResult(app)
            % Check for black hints: Right colour and right position         
            blackIdxLst = app.SubmittedClrLst == app.TrueClrLst;
            results.numBlacks = sum(blackIdxLst);
           
            % Check for white hints: Right colour wrong position
            pruneTrueClrLst = app.TrueClrLst(~blackIdxLst);
            pruneSubmittedClrLst = app.SubmittedClrLst(~blackIdxLst);
            results.numWhites = 0;
            for cc = pruneTrueClrLst        % Only find first intance to prevent doube counting
                idx = find(cc == pruneSubmittedClrLst,1); 
                results.numWhites = results.numWhites + length(idx);
                pruneSubmittedClrLst(idx) = [];
            end          
            
        end
        
        function updateHintRow(app,results)
            if app.rowNum == 1
                rr = 1;
            else
                rr = app.rowNum - 1;
            end
            for cc = 1:results.numBlacks
                app.HintLamp(rr,cc).Color = "Black";
            end
            for cc = results.numBlacks+(1:results.numWhites)
                app.HintLamp(rr,cc).Color = "White";
            end            
        end
        
        function fancyEnd(app)
            app.CoverBox.Position = [105 560 270 24];
            app.CoverBox.Text = 'Nice!';

%              clrLightLst = {[1 0.5 0.5], [0.5 0.5 1], [1 0.5 1], [1 1 0.5],  [0.5 1 0.5], [0.5 1 1]};
%             for ii = 1:10
%                 for cc = 1:app.numCols
%                     app.Lamp(1,cc).Color = app.clrLst{app.clrNumLst(cc)};
%                 end
% 
%                 for cc = 1:app.numCols
%                     app.Lamp(1,cc).Color = clrLightLst{app.clrNumLst(cc)};
%                 end
%             end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            createLampRow(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            createTargetColourRow(app)
            createCoverBox(app)
            createDropDownRow(app)
            app.StartButton.Enable = 'off';
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
            %   Disable Submit button
            app.SubmitButton.Enable = 'off';
            
            %   Disable previous row
            for cc = 1:app.numCols
               app.SubmittedClrLst(cc) = string(app.DropDown(app.rowNum,cc).Value);
               app.DropDown(app.rowNum,cc).Enable = 'off';
            end
            
            % Check result
            results = checkResult(app);

            if results.numBlacks == app.numCols
                app.rowNum = app.rowNum + 1;
                fancyEnd(app)
            else
                % Create next dropdown row
                createDropDownRow(app)
            end

            % Update hint row
            updateHintRow(app,results)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Enable = 'off';
            app.SubmitButton.Text = 'Submit';
            app.Label_Chance_static = uilabel(app.UIFigure);
            app.Label_Chance_static.Text = 'Chances remaining';
            app.Label_Chance_number = uilabel(app.UIFigure);
            app.Label_Chance_number.Text = '10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SuperHirn

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end